classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Main                   matlab.ui.container.Tab
        ForwardModel           matlab.ui.container.Tab
        TextArea_ForwardModel  matlab.ui.control.TextArea
        LabelTextArea          matlab.ui.control.Label
        Button_AddLinOp        matlab.ui.control.Button
        Button_SeeDoc          matlab.ui.control.Button
        LabelDropDown          matlab.ui.control.Label
        DropDown_SelectLinOp   matlab.ui.control.DropDown
        Next                   matlab.ui.control.Button
        FMPanel                matlab.ui.container.Panel
        CostFunction           matlab.ui.container.Tab
        Optimization           matlab.ui.container.Tab
        Label                  matlab.ui.control.Label
    end


    properties (Access = public)

        % Components progamatically added in ForwardModel panel  
        dd             % Order indices (1,2,3,etc.) 
        ddlabel        % 'Order' label
        linOplabel     % Displayed label of selected linOp
        butProp        % Open a window to add properties (input, CTF, etc.)
        butDel         % To delete linOp 
        
    end
    
    properties (Access = private)
      
        % Global properties
        lib_path = '/Users/laurene/Documents/libraries/GlobalBioIm/'; % Path to GlobalBioIm
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation 
        
        % Properties for ForwardModel panel  
        nLinOps          = 0;      % number of selected linOps
        linOps_order     = [];     % order of selected linOps 
        linOps_list      = {};     % list of selected linOps 
        maxLinOp         = 6;      % maximum number of concatenable linOps
        linOpText        = [];     % info displayed in text area for user 

        
        % Information for display 
        pos_left      = 50; 
        pos_bott_init = 325;
        incr_height   = 50;
        def_width     = 30; 
        def_height    = 20;
        
    end

    methods (Access = private)
        
        % FORWARD MODEL PANEL ------------------
        
        % Set list of linOps in dropdown menu
        function ListOfLinOpsStr = getStringListOfAvailableLinOps(app)
            cd([app.lib_path 'LinOp/'])  
            dirList1 = struct2cell(dir('LinOp*.m')); 
            dirList2 = struct2cell(dir('SelectorLinOps/LinOp*.m')); 
            dirList = [dirList1 dirList2]; 
            ListOfLinOpsStr = strrep(strrep(dirList(1,:),'.m',''),'LinOp','');  
        end
        
        % Delete hierarchy of linOps
        function deleteHierarchyLinOps(app)
            delete(app.dd); 
            delete(app.ddlabel);
            delete(app.linOplabel);
            delete(app.butProp);
            delete(app.butDel);
        end 
        
        % Create hierarchy of linOps
        function createHierarchyLinOps(app)
            
           % Position info  
           pos_bott      = app.pos_bott_init-(app.nLinOps-1)*app.incr_height;
             
           % Update text for concatenation of linOps 
           app.TextArea_ForwardModel.FontSize=16; 
           if app.nLinOps==1
               app.linOpText = ['H = H' char(8321)]; 
           else 
               app.linOpText = [app.linOpText ' H' char(8320+app.nLinOps)];
           end 
           app.TextArea_ForwardModel.Value = {app.linOpText};
           app.TextArea_ForwardModel.HorizontalAlignment = 'center';  
           
           % Create label of drop-down component 
           app.ddlabel = uilabel(app.ForwardModel); 
           app.ddlabel.Position = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
           app.ddlabel.Text = 'Order';
           
           % Create drop-down component 
           app.dd = uidropdown(app.ForwardModel);
           app.dd.Items = {num2str(app.nLinOps)}; % NOTE: Here will need to add the correct number of options
           app.dd.Position = [app.pos_left+40 pos_bott app.def_width app.def_height]; 
           
           % Add linOp name info
           app.linOplabel = uilabel(app.ForwardModel); 
           app.linOplabel.Text = ['LinOp' app.DropDown_SelectLinOp.Value];
           app.linOplabel.Position = [app.pos_left+80 pos_bott app.def_width*4 app.def_height]; 
           
           % Create button for setting properties 
           % NOTE: HERE WE NEED TO ADD THE OPEN WINDOWS OPTION 
           app.butProp = uibutton(app.ForwardModel); 
           app.butProp.Text = 'Set Properties';
           app.butProp.FontWeight = 'bold';  
           app.butProp.Position = [app.pos_left+200 pos_bott app.def_width*3 app.def_height]; 
           
           % Create delete button 
           % NOTE: HERE WE NEED TO HANDLE THE DECREMENT OF NUMBER OF LINOPS AND DELETING OF STUFF
           app.butDel = uibutton(app.ForwardModel); 
           app.butDel.Text = 'Delete';
           app.butDel.Position = [app.pos_left+300 pos_bott app.def_width*2 app.def_height];
        end 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % GENERAL ---------------
            
            % MAIN PANEL --------------
            
            % FORWARD MODEL PANEL ------------------
            ListOfLinOpsStr = getStringListOfAvailableLinOps(app); 
            app.DropDown_SelectLinOp.Items = ListOfLinOpsStr; 

        end

        % Button pushed function: Button_AddLinOp
        function Button_AddLinOpButtonPushed(app, event)
           % Increment number of linOps  
           app.nLinOps = app.nLinOps+1; 
           
           % Delete previously displayed text in the hierarchy box 
           if app.nLinOps>1
               deleteHierarchyLinOps(app); 
           end 
  
           % Display updated text in the hierarchy box 
           createHierarchyLinOps(app); 
           
           % Disable addLinOp button if max number of linOps reached
           if app.nLinOps>=app.maxLinOp 
              app.Button_AddLinOp.Enable = 'off'; 
           end
        end

        % Button pushed function: Button_SeeDoc
        function Button_SeeDocButtonPushed(app, event)
           linOpName = app.DropDown_SelectLinOp.Value;
           web([app.doc_weblink 'linop.html#linop' lower(linOpName)]); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextArea_ForwardModel = uitextarea(app.ForwardModel);
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Text = 'Forward Model';
            app.Button_AddLinOp = uibutton(app.ForwardModel, 'push');
            app.Button_AddLinOp.ButtonPushedFcn = createCallbackFcn(app, @Button_AddLinOpButtonPushed, true);
            app.Button_AddLinOp.Text = 'Add LinOp';
            app.Button_SeeDoc = uibutton(app.ForwardModel, 'push');
            app.Button_SeeDoc.ButtonPushedFcn = createCallbackFcn(app, @Button_SeeDocButtonPushed, true);
            app.Button_SeeDoc.Text = 'See Doc';
            app.LabelDropDown = uilabel(app.ForwardModel);
            app.LabelDropDown.Text = 'Select a LinOp:';
            app.DropDown_SelectLinOp = uidropdown(app.ForwardModel);
            app.DropDown_SelectLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_SelectLinOp.Value = 'Test';
            app.Next = uibutton(app.ForwardModel, 'push');
            app.Next.Text = 'Next';
            app.FMPanel = uipanel(app.ForwardModel);
            app.FMPanel.Title = 'Hierarchy of Selected LinOps';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'GlobalBioIm - GUI';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end